classdef fishFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IndividualChannelsSwitch       matlab.ui.control.RockerSwitch
        IndividualChannelsSwitchLabel  matlab.ui.control.Label
        LoadSettingsButton             matlab.ui.control.Button
        MergeSwitch                    matlab.ui.control.ToggleSwitch
        MergeSwitchLabel               matlab.ui.control.Label
        Ch3ThresholdEditField          matlab.ui.control.NumericEditField
        Ch3ThresholdEditFieldLabel     matlab.ui.control.Label
        Ch2ThresholdEditField          matlab.ui.control.NumericEditField
        Ch2ThresholdEditFieldLabel     matlab.ui.control.Label
        Ch1ThresholdEditField          matlab.ui.control.NumericEditField
        Ch1ThresholdEditFieldLabel     matlab.ui.control.Label
        FastSegmentButton              matlab.ui.control.Button
        BatchProcessLabel              matlab.ui.control.Label
        SaveLabel                      matlab.ui.control.Label
        SetSignalThresholdsLabel       matlab.ui.control.Label
        DefineCellMaskLabel            matlab.ui.control.Label
        SegmentDAPILabel               matlab.ui.control.Label
        LoadImageLabel                 matlab.ui.control.Label
        BatchProcess4ChButton          matlab.ui.control.Button
        BatchProcessRGBButton          matlab.ui.control.Button
        SaveSessionButton              matlab.ui.control.Button
        ShowCh3ThresholdButton         matlab.ui.control.Button
        ShowCh2ThresholdButton         matlab.ui.control.Button
        ShowCh1ThresholdButton         matlab.ui.control.Button
        CellMaskSizeKnob               matlab.ui.control.Knob
        RFOVESegmentButton             matlab.ui.control.Button
        Load4ChImagesButton            matlab.ui.control.Button
        LoadRGBImageButton             matlab.ui.control.Button
        Ch3Slider                      matlab.ui.control.Slider
        Ch2Slider                      matlab.ui.control.Slider
        Ch1Slider                      matlab.ui.control.Slider
        Ch3Axes                        matlab.ui.control.UIAxes
        Ch2Axes                        matlab.ui.control.UIAxes
        Ch1Axes                        matlab.ui.control.UIAxes
        MainImage                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file
        path
        im
        ch0
        ch1
        ch2
        ch3
        dilSize
        nucMask
        cellMask
        cellBound
        cellPixIdx
        ch1Intesity
        ch1Thresh
        ch2Intesity
        ch2Thresh
        ch3Intesity
        ch3Thresh
        ch1SigCellMask
        ch2SigCellMask
        ch3SigCellMask
        overCh1
        overCh2
        overCh3
        finalImage
        segType
        savePath
        batchMode
        dirPath
        h1
        h2
        h3
        ch1Cells
        ch2Cells
        ch3Cells
    end
    
%     properties (Access = private)
%         oldpath = addpath('./fishFunctions');
%     end
    
    methods (Access = private)
        
        function results = updateH1(app)
                hold(app.Ch1Axes, 'off' );
                [app.h1]=histogram(app.Ch1Axes,app.ch1Intesity,100,'FaceColor','g','EdgeAlpha',0);
                hold(app.Ch1Axes, 'on' );
                plot(app.Ch1Axes,[app.Ch1Slider.Value app.Ch1Slider.Value],[0.00001 max(app.h1.Values)*5],'--k','LineWidth',1);
                set(app.Ch1Axes, 'YScale', 'log');
                set(app.Ch1Axes, 'XLim',[app.h1.BinLimits(1)-(app.h1.BinLimits(2)*0.05) app.h1.BinLimits(2)*1.05]);
        end
        
        function results = updateH2(app)
                hold(app.Ch2Axes, 'off' );
                [app.h2]=histogram(app.Ch2Axes,app.ch2Intesity,100,'FaceColor','r','EdgeAlpha',0);
                hold(app.Ch2Axes, 'on' );
                plot(app.Ch2Axes,[app.Ch2Slider.Value app.Ch2Slider.Value],[0.00001 max(app.h2.Values)*5],'--k','LineWidth',1);
                set(app.Ch2Axes, 'YScale', 'log');
                set(app.Ch2Axes, 'XLim',[app.h2.BinLimits(1)-(app.h2.BinLimits(2)*0.05) app.h2.BinLimits(2)*1.05]);
        end
        
        function results = updateH3(app)
                hold(app.Ch3Axes, 'off' );
                [app.h3]=histogram(app.Ch3Axes,app.ch3Intesity,100,'FaceColor','m','EdgeAlpha',0);
                hold(app.Ch3Axes, 'on' );
                plot(app.Ch3Axes,[app.Ch3Slider.Value app.Ch3Slider.Value],[0.00001 max(app.h3.Values)*5],'--k','LineWidth',1);
                set(app.Ch3Axes, 'YScale', 'log');
                set(app.Ch3Axes, 'XLim',[app.h3.BinLimits(1)-(app.h3.BinLimits(2)*0.05) app.h3.BinLimits(2)*1.05]);
        end
        
        function results = createMerge(app)
            A = cellfun(@(x) sum(app.ch1(x))/length(x)>app.ch1Thresh,app.cellPixIdx,'UniformOutput',true);
            sigIdx=find(A==1);
            [~,app.ch1SigCellMask] = ismember(app.cellMask,sigIdx);
            
            A = cellfun(@(x) sum(app.ch2(x))/length(x)>app.ch2Thresh,app.cellPixIdx,'UniformOutput',true);
            sigIdx=find(A==1);
            [~,app.ch2SigCellMask] = ismember(app.cellMask,sigIdx);
            
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                A = cellfun(@(x) sum(app.ch3(x))/length(x)>app.ch3Thresh,app.cellPixIdx,'UniformOutput',true);
                sigIdx=find(A==1);
                [~,app.ch3SigCellMask] = ismember(app.cellMask,sigIdx);
                negCellBound=app.cellBound-(boundarymask(app.ch1SigCellMask) | boundarymask(app.ch2SigCellMask) | boundarymask(app.ch3SigCellMask));
            else
                negCellBound=app.cellBound-(boundarymask(app.ch1SigCellMask) | boundarymask(app.ch2SigCellMask));
            end       
            
            imCellID=labeloverlay(app.im,negCellBound,'Colormap',[1 1 1],'Transparency',.75);
            
            ch1SigCellBounds=boundarymask(app.ch1SigCellMask);
            app.overCh1=labeloverlay(imCellID,ch1SigCellBounds,'Colormap',[0 1 0],'Transparency',.5);
            
            app.ch2SigCellMask=imdilate(app.ch2SigCellMask,strel('disk',3));
            ch2SigCellBounds=boundarymask(app.ch2SigCellMask);
            app.overCh2=labeloverlay(imCellID,ch2SigCellBounds,'Colormap',[1 0 0],'Transparency',.5);
            ch12Cells=labeloverlay(app.overCh1,ch2SigCellBounds,'Colormap',[1 0 0],'Transparency',.5);
            
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                app.ch3SigCellMask=imerode(app.ch3SigCellMask,strel('disk',3));
                ch3SigCellBounds=boundarymask(app.ch3SigCellMask);
                app.overCh3=labeloverlay(imCellID,ch3SigCellBounds,'Colormap',[1 0 1],'Transparency',.5);
                app.finalImage=labeloverlay(ch12Cells,ch3SigCellBounds,'Colormap',[1 0 1],'Transparency',.5);
            else
                app.finalImage=ch12Cells;
            end
            
            switch app.IndividualChannelsSwitch.Value
                case 'Yes'
                    imCellID1=labeloverlay(app.ch1,app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
                    app.ch1Cells=labeloverlay(imCellID1,ch1SigCellBounds,'Colormap',[0 1 0],'Transparency',.5);
                    imCellID2=labeloverlay(app.ch2,app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
                    app.ch2Cells=labeloverlay(imCellID2,ch2SigCellBounds,'Colormap',[1 0 0],'Transparency',.5);
                    if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                        imCellID3=labeloverlay(app.ch3,app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
                        app.ch3Cells=labeloverlay(imCellID3,ch3SigCellBounds,'Colormap',[1 0 1],'Transparency',.5);
                    end
                case 'No'      
            end
            
            imshow(imresize(app.finalImage,.5), 'Parent', app.MainImage);
            drawnow
        end
        
        function results = UpdateCellMask(app)
            app.MergeSwitch.Value='Off';
            app.dilSize= round(app.CellMaskSizeKnob.Value);
            se = strel('disk',app.dilSize);
            app.cellMask=imdilate(app.nucMask,se);
            app.cellBound = boundarymask(app.cellMask);
            imCellID=labeloverlay(imadjust(app.ch0),app.cellBound,'Colormap',[1 1 1],'Transparency',.25);
            
            % Update Cell Mask & Pixel Index
            imshow(imresize(imCellID,.5), 'Parent', app.MainImage);
            drawnow  
            app.cellPixIdx = label2idx(app.cellMask);

            % Ch1 Histogram
            app.ch1Intesity = cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch1Axes, 'off' );
            [app.h1]=histogram(app.Ch1Axes,app.ch1Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch1Slider.Limits=app.h1.BinLimits;
                app.Ch1ThresholdEditField.Value=app.Ch1Slider.Value;
            end
            updateH1(app);

            % Ch2 Histogram
            app.ch2Intesity = cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch2Axes, 'off' );
            [app.h2]=histogram(app.Ch2Axes,app.ch2Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch2Slider.Limits=app.h2.BinLimits;
                app.Ch2ThresholdEditField.Value=app.Ch2Slider.Value;
            end
            updateH2(app);
            
            % Ch3 Histogram
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
            app.ch3Intesity = cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch3Axes, 'off' );
            [app.h3]=histogram(app.Ch3Axes,app.ch3Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch3Slider.Limits=app.h3.BinLimits;
                app.Ch3ThresholdEditField.Value=app.Ch3Slider.Value;
            end
            updateH3(app);
            else
            app.ch3Thresh=inf;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadRGBImageButton
        function LoadRGBImageButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            app.ch0=[]; % DAPI Image
            app.ch1=[]; % Green Channel
            app.ch2=[]; % Red Channel
            app.ch3=[]; % Magenta Channel
            app.im=[];
            [app.file,app.path] = uigetfile(...
                {'*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.png; *.PNG; *.bmp; *.BMP','Supported Files (*.jpg,*.img,*.tiff,*.png,*.bmp)'; ...
                '*.jpg','jpg Files (*.jpg)';...
                '*.JPG','JPG Files (*.JPG)';...
                '*.jpeg','jpeg Files (*.jpeg)';...
                '*.JPEG','JPEG Files (*.JPEG)';...
                '*.img','img Files (*.img)';...
                '*.IMG','IMG Files (*.IMG)';...
                '*.tif','tif Files (*.tif)';...
                '*.TIF','TIF Files (*.TIF)';...
                '*.tiff','tiff Files (*.tiff)';...
                '*.TIFF','TIFF Files (*.TIFF)'; ...
                '*.png','png Files (*.png)';...
                '*.PNG','PNG Files (*.PNG)';...
                '*.bmp','bmp Files (*.bmp)';...
                '*.BMP','BMP Files (*.BMP)'...
                },'Select an RGB Image for Analysis',...
                'MultiSelect', 'off');
            figure(app.UIFigure);
            app.im=imread(fullfile(app.path,app.file));
            app.ch0=app.im(:,:,3); % DAPI Image
            app.ch1=app.im(:,:,2); % Green Channel
            app.ch2=app.im(:,:,1); % Red Channel
            app.ch3=zeros(size(app.ch0));
            imshow(imresize(app.im,.5), 'Parent', app.MainImage);
            drawnow
        end

        % Button pushed function: RFOVESegmentButton
        function RFOVESegmentButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            app.segType='RFOVE';
            fig = uifigure('Position',[500 500 450 125]);
            d = uiprogressdlg(fig,'Title','Segmenting DAPI... Slow & Steady',...
                'Indeterminate','on');
            drawnow
            se = strel('disk',15);
            background = imopen(app.ch0,se);
            app.ch0 = app.ch0 - background;
            
            %% DAPI Segmentation
            METHOD = 1;
            METHODSEG = 4;
            global Constraints
            Constraints = [150 0.2 0.35];
            AICBIC_SELECTION = 1; %Set AICBIC_SELECTION = 1, to use AIC is selected else BIC is used
            set(0,'DefaultFigureColormap',jet);
            NeighborhoodSize = 151;
            [app.nucMask,~,~] = runMainAlgo(imgaussfilt(app.ch0,2),AICBIC_SELECTION,METHOD,METHODSEG,NeighborhoodSize,1,0);
            if isempty(app.batchMode)
                app.dilSize= round(app.CellMaskSizeKnob.Value);
            end
            se = strel('disk',app.dilSize);
            app.cellMask=imdilate(app.nucMask,se);
            app.cellBound = boundarymask(app.cellMask);
            imCellID=labeloverlay(imadjust(app.ch0),app.cellBound,'Colormap',[1 1 1],'Transparency',.25);
            
            % close the dialog box
            close(d);
            close(fig);
            
            % Update Cell Mask & Pixel Index
            imshow(imresize(imCellID,.5), 'Parent', app.MainImage);
            drawnow  
            app.cellPixIdx = label2idx(app.cellMask);

            % Ch1 Histogram
            app.ch1Intesity = cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch1Axes, 'off' );
            [app.h1]=histogram(app.Ch1Axes,app.ch1Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch1Slider.Limits=app.h1.BinLimits;
                app.Ch1Slider.Value=mean(app.h1.BinLimits);
                app.Ch1ThresholdEditField.Value=app.Ch1Slider.Value;
                app.ch1Thresh=app.Ch1Slider.Value;
            end
            updateH1(app);

            % Ch2 Histogram
            app.ch2Intesity = cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch2Axes, 'off' );
            [app.h2]=histogram(app.Ch2Axes,app.ch2Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch2Slider.Limits=app.h2.BinLimits;
                app.Ch2Slider.Value=mean(app.h2.BinLimits);
                app.Ch2ThresholdEditField.Value=app.Ch2Slider.Value;
                app.ch2Thresh=app.Ch2Slider.Value;
            end
            updateH2(app);
            
            % Ch3 Histogram
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
            app.ch3Intesity = cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch3Axes, 'off' );
            [app.h3]=histogram(app.Ch3Axes,app.ch3Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch3Slider.Limits=app.h3.BinLimits;
                app.Ch3Slider.Value=mean(app.h3.BinLimits);
                app.Ch3ThresholdEditField.Value=app.Ch3Slider.Value;
                app.ch3Thresh=app.Ch3Slider.Value;
            end
            updateH3(app);
            else
            app.ch3Thresh=inf;
            end
           
        end

        % Value changed function: Ch1Slider
        function Ch1SliderValueChanged(app, event)
            if ~isempty(app.ch1Intesity) && isempty(app.batchMode)
                app.ch1Thresh=app.Ch1Slider.Value;
                updateH1(app);
                app.Ch1ThresholdEditField.Value=app.Ch1Slider.Value;
            end
        end

        % Value changed function: Ch2Slider
        function Ch2SliderValueChanged(app, event)
            if ~isempty(app.ch2Intesity) && isempty(app.batchMode)
                app.ch2Thresh=app.Ch2Slider.Value;
                updateH2(app);
                app.Ch2ThresholdEditField.Value=app.Ch2Slider.Value;
            end
        end

        % Value changed function: Ch3Slider
        function Ch3SliderValueChanged(app, event)
            if ~isempty(app.ch3Intesity) && isempty(app.batchMode)
                if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                    app.ch3Thresh=app.Ch3Slider.Value;
                    updateH3(app);
                    app.Ch3ThresholdEditField.Value=app.Ch3Slider.Value;
                else
                    app.ch3Thresh=inf;
                end
            end
        end

        % Button pushed function: ShowCh1ThresholdButton
        function ShowCh1ThresholdButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            A = cellfun(@(x) sum(app.ch1(x))/length(x)>app.ch1Thresh,app.cellPixIdx,'UniformOutput',true);
            sigIdx=find(A==1);
            [~,app.ch1SigCellMask] = ismember(app.cellMask,sigIdx);
            ch1SigCellBounds=boundarymask(app.ch1SigCellMask);
            imCellID=labeloverlay(imadjust(app.ch1),app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
            ch1Cells=labeloverlay(imCellID,ch1SigCellBounds,'Colormap',[0 1 0],'Transparency',.5);
            imshow(imresize(ch1Cells,.5), 'Parent', app.MainImage);
            drawnow  
        end

        % Button pushed function: ShowCh2ThresholdButton
        function ShowCh2ThresholdButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            A = cellfun(@(x) sum(app.ch2(x))/length(x)>app.ch2Thresh,app.cellPixIdx,'UniformOutput',true);
            sigIdx=find(A==1);
            [~,app.ch2SigCellMask] = ismember(app.cellMask,sigIdx);
            ch2SigCellBounds=boundarymask(app.ch2SigCellMask);
            imCellID=labeloverlay(imadjust(app.ch2),app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
            ch2Cells=labeloverlay(imCellID,ch2SigCellBounds,'Colormap',[1 0 0],'Transparency',.5);
            imshow(imresize(ch2Cells,.5), 'Parent', app.MainImage);
            drawnow  
        end

        % Button pushed function: ShowCh3ThresholdButton
        function ShowCh3ThresholdButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                A = cellfun(@(x) sum(app.ch3(x))/length(x)>app.ch3Thresh,app.cellPixIdx,'UniformOutput',true);
                sigIdx=find(A==1);
                [~,app.ch3SigCellMask] = ismember(app.cellMask,sigIdx);
                ch3SigCellBounds=boundarymask(app.ch3SigCellMask);
                imCellID=labeloverlay(imadjust(app.ch3),app.cellBound,'Colormap',[1 1 1],'Transparency',.75);
                ch3Cells=labeloverlay(imCellID,ch3SigCellBounds,'Colormap',[1 0 1],'Transparency',.5);
                imshow(imresize(ch3Cells,.5), 'Parent', app.MainImage);
                drawnow  
            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            createMerge(app);
            if iscell(app.file)
            [fileID, ~]=strtok(app.file{1,1},'.');
            [fileID, ~]=strtok(fileID,'_');
            else
            [fileID, ~]=strtok(app.file,'.');
            end
            
            [imFile,imPath] = uiputfile([fileID '_Analyzed.jpg'],'Save Final Image');
            imwrite(app.finalImage,fullfile(imPath,imFile),"jpg");
            
            switch app.IndividualChannelsSwitch.Value
                case 'Yes'
                    imwrite(app.ch1Cells,fullfile(imPath,[fileID '_Ch1_Analyzed.jpg']),"jpg");
                    imwrite(app.ch2Cells,fullfile(imPath,[fileID '_CH2_Analyzed.jpg']),"jpg");
                    if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                        imwrite(app.ch3Cells,fullfile(imPath,[fileID '_CH3_Analyzed.jpg']),"jpg");
                    end
                case 'No'
            end
            
            [setFile,setPath] = uiputfile([fileID '_Settings.mat'],'Save Final Settings for Batch Processing');
            dilSize=app.dilSize;
            ch1Thresh=app.ch1Thresh;
            ch2Thresh=app.ch2Thresh;
            ch3Thresh=app.ch3Thresh;
            segType=app.segType;
            save(fullfile(setPath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
            [statFile,statPath] = uiputfile([fileID '_Stats.xlsx'],'Save Colocalization Statistics');

            totalCells=length(unique(app.nucMask));
            
            ch1Idx = cellfun(@(x) sum(app.ch1(x))/length(x)>app.ch1Thresh,app.cellPixIdx,'UniformOutput',true);
            ch1Cells=sum(ch1Idx);
            ch1CellIntensity = mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1Idx),'UniformOutput',true));
            
            ch2Idx = cellfun(@(x) sum(app.ch2(x))/length(x)>app.ch2Thresh,app.cellPixIdx,'UniformOutput',true);
            ch2Cells=sum(ch2Idx);
            ch2CellIntensity = mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch2Idx),'UniformOutput',true));
            
            ch1ch2Idx=ch1Idx & ch2Idx;
            ch1ch2Cells=sum(ch1ch2Idx);
            ch1Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
            ch2Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
            
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                ch3Idx = cellfun(@(x) sum(app.ch3(x))/length(x)>app.ch3Thresh,app.cellPixIdx,'UniformOutput',true);
                ch3Cells=sum(ch3Idx);
                ch3CellIntensity = mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch3Idx),'UniformOutput',true));
                
                ch1ch3Idx=ch1Idx & ch3Idx;
                ch1ch3Cells=sum(ch1ch3Idx);
                ch1Intensity_ch1ch3Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch3Idx),'UniformOutput',true));
                ch3Intensity_ch1ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch1ch3Idx),'UniformOutput',true));
                
                ch2ch3Idx=ch2Idx & ch3Idx;
                ch2ch3Cells=sum(ch2ch3Idx);
                ch2Intensity_ch2ch3Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch2ch3Idx),'UniformOutput',true));
                ch3Intensity_ch2ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch2ch3Idx),'UniformOutput',true));
                
                ch1ch2ch3Idx=ch1Idx & ch2Idx & ch3Idx;
                ch1ch2ch3Cells=sum(ch1ch2ch3Idx);
                ch1Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                ch2Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                ch3Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                
                T=table(totalCells,ch1Cells,ch2Cells,ch3Cells,ch1CellIntensity,ch2CellIntensity,ch3CellIntensity,...
                    ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells,...
                    ch1ch3Cells,ch1Intensity_ch1ch3Cells,ch3Intensity_ch1ch3Cells,...
                    ch2ch3Cells,ch2Intensity_ch2ch3Cells,ch3Intensity_ch2ch3Cells,...
                    ch1ch2ch3Cells,ch1Intensity_ch1ch2ch3Cells,ch2Intensity_ch1ch2ch3Cells,ch3Intensity_ch1ch2ch3Cells);
                writetable(T,fullfile(statPath,statFile));
            else
                T=table(totalCells,ch1Cells,ch2Cells,ch1CellIntensity,ch2CellIntensity,ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells);
                writetable(T,fullfile(statPath,statFile));
            end
        end

        % Button pushed function: Load4ChImagesButton
        function Load4ChImagesButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            app.ch0=[]; % DAPI Image
            app.ch1=[]; % Green Channel
            app.ch2=[]; % Red Channel
            app.ch3=[]; % Magenta Channel
            app.im=[];
            [app.file,app.path] = uigetfile(...
                {'*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.png; *.PNG; *.bmp; *.BMP','Supported Files (*.jpg,*.img,*.tiff,*.png,*.bmp)'; ...
                '*.jpg','jpg Files (*.jpg)';...
                '*.JPG','JPG Files (*.JPG)';...
                '*.jpeg','jpeg Files (*.jpeg)';...
                '*.JPEG','JPEG Files (*.JPEG)';...
                '*.img','img Files (*.img)';...
                '*.IMG','IMG Files (*.IMG)';...
                '*.tif','tif Files (*.tif)';...
                '*.TIF','TIF Files (*.TIF)';...
                '*.tiff','tiff Files (*.tiff)';...
                '*.TIFF','TIFF Files (*.TIFF)'; ...
                '*.png','png Files (*.png)';...
                '*.PNG','PNG Files (*.PNG)';...
                '*.bmp','bmp Files (*.bmp)';...
                '*.BMP','BMP Files (*.BMP)'...
                }, 'Select 4 Images _ch0; _ch1; _ch2; _ch3','MultiSelect','on');
            figure(app.UIFigure);
            app.ch0=imread(fullfile(app.path,app.file{1,1}));
            app.ch1=imread(fullfile(app.path,app.file{1,2}));
            app.ch2=imread(fullfile(app.path,app.file{1,3}));
            app.ch3=imread(fullfile(app.path,app.file{1,4}));
            if size(app.ch0,3)==3
               app.ch0=max(app.ch0,[],3); 
            end
            if size(app.ch1,3)==3
               app.ch1=max(app.ch1,[],3); 
            end
            if size(app.ch2,3)==3
               app.ch2=max(app.ch2,[],3); 
            end
            if size(app.ch3,3)==3
               app.ch3=max(app.ch3,[],3); 
            end
            app.im(:,:,1)=mat2gray(imfuse(app.ch2,app.ch3,'blend','Scaling','none'));
            app.im(:,:,2)=mat2gray(app.ch1);
            app.im(:,:,3)=mat2gray(imfuse(app.ch0,app.ch3,'blend','Scaling','none'));
            imshow(imresize(app.im,.5), 'Parent', app.MainImage);
            drawnow  
        end

        % Button pushed function: FastSegmentButton
        function FastSegmentButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            app.segType='sFast';
            fig = uifigure('Position',[500 500 450 125]);
            d = uiprogressdlg(fig,'Title','Segmenting DAPI... Quick & Dirty',...
                'Indeterminate','on');
            drawnow
            
            imBl = app.ch0;
            T = adaptthresh(imBl, 0.4);
            imBl = imbinarize(imBl,T);
            imBl = bwareaopen(imBl,15);
            imBl = imfill(imBl,'holes');
            cc_blue = bwconncomp(imBl, 8);
            numOfPixels = cellfun(@numel,cc_blue.PixelIdxList);
            maxPix=max(numOfPixels);
            areaFilter=[median(numOfPixels)*.25, median(numOfPixels)*2];
            BW2 = imBl;
            
            while maxPix>areaFilter(2)
                bw_temp = bwareafilt(BW2,areaFilter);
                seD = strel('diamond',1);
                BW2 = imerode(BW2,seD);
                BW2 = or(bw_temp,BW2);
                cc_blue = bwconncomp(BW2, 8);
                numOfPixels = cellfun(@numel,cc_blue.PixelIdxList);
                maxPix=max(numOfPixels);
            end
            
            app.nucMask=bwlabel(BW2);
            if isempty(app.batchMode)
                app.dilSize= round(app.CellMaskSizeKnob.Value);
            end
            se = strel('disk',app.dilSize);
            app.cellMask=imdilate(app.nucMask,se);
            app.cellBound = boundarymask(app.cellMask);
            imCellID=labeloverlay(imadjust(app.ch0),app.cellBound,'Colormap',[1 1 1],'Transparency',.25);
            
            % close the dialog box
            close(d);
            close(fig);
            
            % Update Cell Mask & Pixel Index
            imshow(imresize(imCellID,.5), 'Parent', app.MainImage);
            drawnow  
            app.cellPixIdx = label2idx(app.cellMask);

            % Ch1 Histogram
            app.ch1Intesity = cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch1Axes, 'off' );
            [app.h1]=histogram(app.Ch1Axes,app.ch1Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch1Slider.Limits=app.h1.BinLimits;
                app.Ch1Slider.Value=mean(app.h1.BinLimits);
                app.Ch1ThresholdEditField.Value=app.Ch1Slider.Value;
                app.ch1Thresh=app.Ch1Slider.Value;
            end
            updateH1(app);

            % Ch2 Histogram
            app.ch2Intesity = cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch2Axes, 'off' );
            [app.h2]=histogram(app.Ch2Axes,app.ch2Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch2Slider.Limits=app.h2.BinLimits;
                app.Ch2Slider.Value=mean(app.h2.BinLimits);
                app.Ch2ThresholdEditField.Value=app.Ch2Slider.Value;
                app.ch2Thresh=app.Ch2Slider.Value;
            end
            updateH2(app);
            
            % Ch3 Histogram
            if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
            app.ch3Intesity = cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx,'UniformOutput',true);
            hold(app.Ch3Axes, 'off' );
            [app.h3]=histogram(app.Ch3Axes,app.ch3Intesity,100,'FaceColor','g','EdgeAlpha',0);
            if isempty(app.batchMode)
                app.Ch3Slider.Limits=app.h3.BinLimits;
                app.Ch3Slider.Value=mean(app.h3.BinLimits);
                app.Ch3ThresholdEditField.Value=app.Ch3Slider.Value;
                app.ch3Thresh=app.Ch3Slider.Value;
            end
            updateH3(app);
            else
            app.ch3Thresh=inf;
            end
        end

        % Button pushed function: BatchProcessRGBButton
        function BatchProcessRGBButtonPushed(app, event)
            app.MergeSwitch.Value='Off';
            app.ch0=[]; % DAPI Image
            app.ch1=[]; % Green Channel
            app.ch2=[]; % Red Channel
            app.ch3=[]; % Magenta Channel
            app.im=[];
            [app.file,app.path] = uigetfile(...
                {'*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.png; *.PNG; *.bmp; *.BMP','Supported Files (*.jpg,*.img,*.tiff,*.png,*.bmp)'; ...
                '*.jpg','jpg Files (*.jpg)';...
                '*.JPG','JPG Files (*.JPG)';...
                '*.jpeg','jpeg Files (*.jpeg)';...
                '*.JPEG','JPEG Files (*.JPEG)';...
                '*.img','img Files (*.img)';...
                '*.IMG','IMG Files (*.IMG)';...
                '*.tif','tif Files (*.tif)';...
                '*.TIF','TIF Files (*.TIF)';...
                '*.tiff','tiff Files (*.tiff)';...
                '*.TIFF','TIFF Files (*.TIFF)'; ...
                '*.png','png Files (*.png)';...
                '*.PNG','PNG Files (*.PNG)';...
                '*.bmp','bmp Files (*.bmp)';...
                '*.BMP','BMP Files (*.BMP)'...
                },'Select RGB Images for Batch Analysis',...
                'MultiSelect', 'on');
            [setFile,setPath] = uigetfile('*.mat*',...
                'Select Settings File for Batch Analysis','MultiSelect','off');
            load(fullfile(setPath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
            app.savePath = uigetdir(app.path,'Select Folder to Save Analysis');
            figure(app.UIFigure);
            app.ch1Thresh=ch1Thresh;
            app.ch2Thresh=ch2Thresh;
            app.ch3Thresh=ch3Thresh;
            app.dilSize=dilSize;
            app.segType=segType;
            app.batchMode=1;
            
            fig2 = uifigure('Position',[500 650 450 125]);
            d2 = uiprogressdlg(fig2,'Title','Batch Analyzing Images',...
            'Message',['1/' num2str(width(app.file))]);
            drawnow
            
            for i=1:width(app.file)
                app.im=imread(fullfile(app.path,app.file{i}));
                app.ch0=app.im(:,:,3); % DAPI Image
                app.ch1=app.im(:,:,2); % Green Channel
                app.ch2=app.im(:,:,1); % Red Channel
                app.ch3=zeros(size(app.ch0));
                imshow(imresize(app.im,.5), 'Parent', app.MainImage);
                drawnow
                
                d2.Value = i/width(app.file); 
                d2.Message = [num2str(i) '/' num2str(width(app.file))];
                  

                if app.segType=='RFOVE'
                    RFOVESegmentButtonPushed(app, event)
                else
                    FastSegmentButtonPushed(app, event)
                end
                
                createMerge(app);
                [fileID, ~]=strtok(app.file{i},'.');
                imwrite(app.finalImage,fullfile(app.savePath,[fileID '_Analyzed.jpg']),"jpg");
                switch app.IndividualChannelsSwitch.Value
                case 'Yes'
                    imwrite(app.ch1Cells,fullfile(app.savePath,[fileID '_Ch1_Analyzed.jpg']),"jpg");
                    imwrite(app.ch2Cells,fullfile(app.savePath,[fileID '_CH2_Analyzed.jpg']),"jpg");
                case 'No'
                end
                
                % Save Table
                totalCells=length(unique(app.nucMask));
                ch1Idx = cellfun(@(x) sum(app.ch1(x))/length(x)>app.ch1Thresh,app.cellPixIdx,'UniformOutput',true);
                ch1Cells=sum(ch1Idx);
                ch1CellIntensity = mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1Idx),'UniformOutput',true));
                ch2Idx = cellfun(@(x) sum(app.ch2(x))/length(x)>app.ch2Thresh,app.cellPixIdx,'UniformOutput',true);
                ch2Cells=sum(ch2Idx);
                ch2CellIntensity = mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch2Idx),'UniformOutput',true));
                ch1ch2Idx=ch1Idx & ch2Idx;
                ch1ch2Cells=sum(ch1ch2Idx);
                ch1Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
                ch2Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
                ID=categorical({fileID});
                if i==1
                    T=table(ID,totalCells,ch1Cells,ch2Cells,ch1CellIntensity,ch2CellIntensity,ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells);
                else
                    T=[T; table(ID,totalCells,ch1Cells,ch2Cells,ch1CellIntensity,ch2CellIntensity,ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells)];
                end
            end
            % close the dialog box
            close(d2);
            close(fig2);
            [batchName, ~]=strtok(app.file{1},'.');
            writetable(T,fullfile(app.savePath,['Batch_' batchName '.xlsx']));
            save(fullfile(app.savePath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
        end

        % Button pushed function: BatchProcess4ChButton
        function BatchProcess4ChButtonPushed(app, event)
            app.MergeSwitch.Value='On';
            app.ch0=[]; % DAPI Image
            app.ch1=[]; % Green Channel
            app.ch2=[]; % Red Channel
            app.ch3=[]; % Magenta Channel
            app.im=[];
            app.dirPath = uigetdir([],'Select Parent Directory Containing Folder to Analyze');
            [setFile,setPath] = uigetfile('*.mat*',...
                'Select Settings File for Batch Analysis','MultiSelect','off');
            load(fullfile(setPath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
            app.savePath = uigetdir(app.path,'Select Folder to Save Analysis');
            figure(app.UIFigure);
            app.ch1Thresh=ch1Thresh;
            app.ch2Thresh=ch2Thresh;
            app.ch3Thresh=ch3Thresh;
            app.dilSize=dilSize;
            app.segType=segType;
            app.batchMode=1;
            fig2 = uifigure('Position',[500 650 450 125]);
            d2 = uiprogressdlg(fig2,'Title','Batch Analyzing Images',...
                'Message',['1/' num2str(width(app.file))]);
            drawnow
            dirs=dir(app.dirPath);
            % Get a logical vector that tells which is a directory.
            dirFlags = [dirs.isdir];
            % Extract only those that are directories.
            subFolders = dirs(dirFlags);
            subFolders = subFolders(3:end,:);
            for i=1:height(subFolders)
                curFold=fullfile(subFolders(i).folder,subFolders(i).name);
                d=dir(curFold);
                idx=cellfun(@(x) contains(x,["jpg", "jpeg", "tif", "tiff", "png", "bmp"],'IgnoreCase',true),{d.name},'UniformOutput',true);
                d=d(idx);
                
                app.ch0=imread(fullfile(d(1).folder,d(1).name));
                app.ch1=imread(fullfile(d(2).folder,d(2).name));
                app.ch2=imread(fullfile(d(3).folder,d(3).name));
                app.ch3=imread(fullfile(d(4).folder,d(4).name));
                if size(app.ch0,3)==3
                    app.ch0=max(app.ch0,[],3);
                end
                if size(app.ch1,3)==3
                    app.ch1=max(app.ch1,[],3);
                end
                if size(app.ch2,3)==3
                    app.ch2=max(app.ch2,[],3);
                end
                if size(app.ch3,3)==3
                    app.ch3=max(app.ch3,[],3);
                end
                app.im(:,:,1)=mat2gray(imfuse(app.ch2,app.ch3,'blend','Scaling','none'));
                app.im(:,:,2)=mat2gray(app.ch1);
                app.im(:,:,3)=mat2gray(imfuse(app.ch0,app.ch3,'blend','Scaling','none'));
                imshow(imresize(app.im,.5), 'Parent', app.MainImage);
                drawnow
                
                
                d2.Value = i/height(subFolders);
                d2.Message = [num2str(i) '/' num2str(height(subFolders))];
                
                
                if app.segType=='RFOVE'
                    RFOVESegmentButtonPushed(app, event)
                else
                    FastSegmentButtonPushed(app, event)
                end
                
                createMerge(app);
                fileID=subFolders(i).name;
                imwrite(app.finalImage,fullfile(app.savePath,[fileID '_Analyzed.jpg']),"jpg");
                switch app.IndividualChannelsSwitch.Value
                case 'Yes'
                    imwrite(app.ch1Cells,fullfile(app.savePath,[fileID '_Ch1_Analyzed.jpg']),"jpg");
                    imwrite(app.ch2Cells,fullfile(app.savePath,[fileID '_CH2_Analyzed.jpg']),"jpg");
                    imwrite(app.ch3Cells,fullfile(app.savePath,[fileID '_CH3_Analyzed.jpg']),"jpg");
                case 'No'
                end
                
                ID=categorical({fileID});
                totalCells=length(unique(app.nucMask));
                
                ch1Idx = cellfun(@(x) sum(app.ch1(x))/length(x)>app.ch1Thresh,app.cellPixIdx,'UniformOutput',true);
                ch1Cells=sum(ch1Idx);
                ch1CellIntensity = mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1Idx),'UniformOutput',true));
                
                ch2Idx = cellfun(@(x) sum(app.ch2(x))/length(x)>app.ch2Thresh,app.cellPixIdx,'UniformOutput',true);
                ch2Cells=sum(ch2Idx);
                ch2CellIntensity = mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch2Idx),'UniformOutput',true));
                
                ch1ch2Idx=ch1Idx & ch2Idx;
                ch1ch2Cells=sum(ch1ch2Idx);
                ch1Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
                ch2Intensity_ch1ch2Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch1ch2Idx),'UniformOutput',true));
                
                ch3Idx = cellfun(@(x) sum(app.ch3(x))/length(x)>app.ch3Thresh,app.cellPixIdx,'UniformOutput',true);
                ch3Cells=sum(ch3Idx);
                ch3CellIntensity = mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch3Idx),'UniformOutput',true));
                
                ch1ch3Idx=ch1Idx & ch3Idx;
                ch1ch3Cells=sum(ch1ch3Idx);
                ch1Intensity_ch1ch3Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch3Idx),'UniformOutput',true));
                ch3Intensity_ch1ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch1ch3Idx),'UniformOutput',true));
                
                ch2ch3Idx=ch2Idx & ch3Idx;
                ch2ch3Cells=sum(ch2ch3Idx);
                ch2Intensity_ch2ch3Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch2ch3Idx),'UniformOutput',true));
                ch3Intensity_ch2ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch2ch3Idx),'UniformOutput',true));
                
                ch1ch2ch3Idx=ch1Idx & ch2Idx & ch3Idx;
                ch1ch2ch3Cells=sum(ch1ch2ch3Idx);
                ch1Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch1(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                ch2Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch2(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                ch3Intensity_ch1ch2ch3Cells=mean(cellfun(@(x) sum(app.ch3(x))/length(x),app.cellPixIdx(ch1ch2ch3Idx),'UniformOutput',true));
                
                ID=categorical({fileID});
                if i==1
                    T=table(ID,totalCells,ch1Cells,ch2Cells,ch3Cells,ch1CellIntensity,ch2CellIntensity,ch3CellIntensity,...
                        ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells,...
                        ch1ch3Cells,ch1Intensity_ch1ch3Cells,ch3Intensity_ch1ch3Cells,...
                        ch2ch3Cells,ch2Intensity_ch2ch3Cells,ch3Intensity_ch2ch3Cells,...
                        ch1ch2ch3Cells,ch1Intensity_ch1ch2ch3Cells,ch2Intensity_ch1ch2ch3Cells,ch3Intensity_ch1ch2ch3Cells);
                else
                    T=[T; table(ID,totalCells,ch1Cells,ch2Cells,ch3Cells,ch1CellIntensity,ch2CellIntensity,ch3CellIntensity,...
                        ch1ch2Cells,ch1Intensity_ch1ch2Cells,ch2Intensity_ch1ch2Cells,...
                        ch1ch3Cells,ch1Intensity_ch1ch3Cells,ch3Intensity_ch1ch3Cells,...
                        ch2ch3Cells,ch2Intensity_ch2ch3Cells,ch3Intensity_ch2ch3Cells,...
                        ch1ch2ch3Cells,ch1Intensity_ch1ch2ch3Cells,ch2Intensity_ch1ch2ch3Cells,ch3Intensity_ch1ch2ch3Cells)];
                end
                
            end
            % close the dialog box
            close(d2);
            close(fig2);
            batchName=strsplit(app.dirPath,'\');
            batchName=batchName(end);
            writetable(T,fullfile(app.savePath,['Batch_' batchName{:} '.xlsx']));
            save(fullfile(app.savePath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
        end

        % Value changed function: Ch1ThresholdEditField
        function Ch1ThresholdEditFieldValueChanged(app, event)
            if ~isempty(app.ch1Intesity) && isempty(app.batchMode)
                if app.Ch1ThresholdEditField.Value<0
                   app.Ch1ThresholdEditField.Value=0;
                elseif app.Ch1ThresholdEditField.Value>app.h1.BinLimits(2)
                    app.Ch1ThresholdEditField.Value=app.h1.BinLimits(2);
                end
                app.ch1Thresh=app.Ch1ThresholdEditField.Value
                app.Ch1Slider.Value=app.Ch1ThresholdEditField.Value
                updateH1(app);
            end
        end

        % Value changed function: Ch2ThresholdEditField
        function Ch2ThresholdEditFieldValueChanged(app, event)
            if ~isempty(app.ch2Intesity) && isempty(app.batchMode)
                if app.Ch2ThresholdEditField.Value<0
                   app.Ch2ThresholdEditField.Value=0;
                elseif app.Ch2ThresholdEditField.Value>app.h2.BinLimits(2)
                    app.Ch2ThresholdEditField.Value=app.h2.BinLimits(2);
                end
                app.ch2Thresh=app.Ch2ThresholdEditField.Value
                app.Ch2Slider.Value=app.Ch2ThresholdEditField.Value
                updateH2(app);
            end            
        end

        % Value changed function: Ch3ThresholdEditField
        function Ch3ThresholdEditFieldValueChanged(app, event)
            if ~isempty(app.ch3Intesity) && isempty(app.batchMode)
                if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all")
                    if app.Ch3ThresholdEditField.Value<0
                        app.Ch3ThresholdEditField.Value=0;
                    elseif app.Ch3ThresholdEditField.Value>app.h3.BinLimits(2)
                        app.Ch3ThresholdEditField.Value=app.h3.BinLimits(2);
                    end
                    app.ch3Thresh=app.Ch3ThresholdEditField.Value
                    app.Ch3Slider.Value=app.Ch3ThresholdEditField.Value
                    updateH3(app);
                else
                    app.ch3Thresh=inf;
                end
            end
        end

        % Value changed function: MergeSwitch
        function MergeSwitchValueChanged(app, event)
            if ~isempty(app.ch1Intesity)
            switch app.MergeSwitch.Value
                case 'On'
                    createMerge(app);
                case 'Off';
                    imshow(imresize(app.im,.5), 'Parent', app.MainImage);
                    drawnow
            end
            end
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            if ~isempty(app.im)
            [setFile,setPath] = uigetfile('*.mat*',...
                'Select Settings File','MultiSelect','off');
            load(fullfile(setPath,setFile),'dilSize','ch1Thresh','ch2Thresh','ch3Thresh','segType');
            app.batchMode=[];
            app.segType=segType;
            app.dilSize=dilSize;
            app.CellMaskSizeKnob.Value=dilSize;
            
                if app.segType=='RFOVE'
                    RFOVESegmentButtonPushed(app, event)
                else
                    FastSegmentButtonPushed(app, event)
                end
            
            app.ch1Thresh=ch1Thresh;
            app.Ch1ThresholdEditField.Value=ch1Thresh;
            app.Ch1Slider.Value=ch1Thresh;
            updateH1(app);
            
            app.ch2Thresh=ch2Thresh;
            app.Ch2ThresholdEditField.Value=ch2Thresh;
            app.Ch2Slider.Value=ch2Thresh;
            updateH2(app);
            
            if ~isempty(app.ch3Intesity) && isempty(app.batchMode)
                if sum(ones(size(app.ch0)),"all") ~= sum(app.ch3==zeros(size(app.ch0)),"all") && ch3Thresh~=inf;
            app.ch3Thresh=ch3Thresh;
            app.Ch3ThresholdEditField.Value=ch3Thresh;
            app.Ch3Slider.Value=ch3Thresh;
            updateH3(app);
                end
            end
            
            end
        end

        % Value changed function: IndividualChannelsSwitch
        function IndividualChannelsSwitchValueChanged(app, event)
            value = app.IndividualChannelsSwitch.Value;         
        end

        % Value changed function: CellMaskSizeKnob
        function CellMaskSizeKnobValueChanged(app, event)
            if ~isempty(app.im)
            UpdateCellMask(app);
            end
        end

        % Value changing function: CellMaskSizeKnob
        function CellMaskSizeKnobValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainImage = uiaxes(app.UIFigure);
            app.MainImage.XTick = [];
            app.MainImage.XTickLabel = '';
            app.MainImage.YTick = [];
            app.MainImage.YTickLabel = '';
            app.MainImage.ZTick = [];
            colormap(app.MainImage, 'jet')
            app.Ch1Axes = uiaxes(app.UIFigure);
            title(app.Ch1Axes, 'Ch 1 Intensity Threshold')
            ylabel(app.Ch1Axes, 'Number of Cells')
            zlabel(app.Ch1Axes, 'Z')
            colormap(app.Ch1Axes, 'jet')
            app.Ch2Axes = uiaxes(app.UIFigure);
            title(app.Ch2Axes, 'Ch 2 Intensity Threshold')
            ylabel(app.Ch2Axes, 'Number of Cells')
            zlabel(app.Ch2Axes, 'Z')
            colormap(app.Ch2Axes, 'jet')
            app.Ch3Axes = uiaxes(app.UIFigure);
            title(app.Ch3Axes, 'Ch 3 Intensity Threshold')
            ylabel(app.Ch3Axes, 'Number of Cells')
            zlabel(app.Ch3Axes, 'Z')
            colormap(app.Ch3Axes, 'jet')
            app.Ch1Slider = uislider(app.UIFigure);
            app.Ch1Slider.ValueChangedFcn = createCallbackFcn(app, @Ch1SliderValueChanged, true);
            app.Ch1Slider.Value = 50;
            app.Ch2Slider = uislider(app.UIFigure);
            app.Ch2Slider.ValueChangedFcn = createCallbackFcn(app, @Ch2SliderValueChanged, true);
            app.Ch2Slider.Value = 50;
            app.Ch3Slider = uislider(app.UIFigure);
            app.Ch3Slider.ValueChangedFcn = createCallbackFcn(app, @Ch3SliderValueChanged, true);
            app.Ch3Slider.Value = 50;
            app.LoadRGBImageButton = uibutton(app.UIFigure, 'push');
            app.LoadRGBImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRGBImageButtonPushed, true);
            app.LoadRGBImageButton.Text = 'Load RGB Image';
            app.Load4ChImagesButton = uibutton(app.UIFigure, 'push');
            app.Load4ChImagesButton.ButtonPushedFcn = createCallbackFcn(app, @Load4ChImagesButtonPushed, true);
            app.Load4ChImagesButton.Text = 'Load 4Ch Images';
            app.RFOVESegmentButton = uibutton(app.UIFigure, 'push');
            app.RFOVESegmentButton.ButtonPushedFcn = createCallbackFcn(app, @RFOVESegmentButtonPushed, true);
            app.RFOVESegmentButton.Text = 'RFOVE Segment';
            app.CellMaskSizeKnob = uiknob(app.UIFigure, 'continuous');
            app.CellMaskSizeKnob.Limits = [1 15];
            app.CellMaskSizeKnob.MajorTicks = [1 3 5 7 9 11 13 15];
            app.CellMaskSizeKnob.ValueChangedFcn = createCallbackFcn(app, @CellMaskSizeKnobValueChanged, true);
            app.CellMaskSizeKnob.ValueChangingFcn = createCallbackFcn(app, @CellMaskSizeKnobValueChanging, true);
            app.CellMaskSizeKnob.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.CellMaskSizeKnob.Value = 8;
            app.ShowCh1ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ShowCh1ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCh1ThresholdButtonPushed, true);
            app.ShowCh1ThresholdButton.Text = 'Show Ch1 Threshold';
            app.ShowCh2ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ShowCh2ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCh2ThresholdButtonPushed, true);
            app.ShowCh2ThresholdButton.Text = 'Show Ch2 Threshold';
            app.ShowCh3ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ShowCh3ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCh3ThresholdButtonPushed, true);
            app.ShowCh3ThresholdButton.Text = 'Show Ch3 Threshold';
            app.SaveSessionButton = uibutton(app.UIFigure, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Text = 'Save Session';
            app.BatchProcessRGBButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessRGBButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessRGBButtonPushed, true);
            app.BatchProcessRGBButton.Text = 'Batch Process RGB';
            app.BatchProcess4ChButton = uibutton(app.UIFigure, 'push');
            app.BatchProcess4ChButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcess4ChButtonPushed, true);
            app.BatchProcess4ChButton.Text = 'Batch Process 4Ch';
            app.LoadImageLabel = uilabel(app.UIFigure);
            app.LoadImageLabel.Text = '1. Load Image';
            app.SegmentDAPILabel = uilabel(app.UIFigure);
            app.SegmentDAPILabel.Text = '2. Segment DAPI';
            app.DefineCellMaskLabel = uilabel(app.UIFigure);
            app.DefineCellMaskLabel.Text = '3. Define Cell Mask';
            app.SetSignalThresholdsLabel = uilabel(app.UIFigure);
            app.SetSignalThresholdsLabel.Text = '4. Set Signal Thresholds';
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = '5. Save';
            app.BatchProcessLabel = uilabel(app.UIFigure);
            app.BatchProcessLabel.Text = '6. Batch Process';
            app.FastSegmentButton = uibutton(app.UIFigure, 'push');
            app.FastSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @FastSegmentButtonPushed, true);
            app.FastSegmentButton.Text = 'Fast Segment';
            app.Ch1ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.Ch1ThresholdEditFieldLabel.Text = 'Ch 1 Threshold';
            app.Ch1ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch1ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Ch1ThresholdEditFieldValueChanged, true);
            app.Ch2ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.Ch2ThresholdEditFieldLabel.Text = 'Ch 2 Threshold';
            app.Ch2ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch2ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Ch2ThresholdEditFieldValueChanged, true);
            app.Ch3ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.Ch3ThresholdEditFieldLabel.Text = 'Ch 3 Threshold';
            app.Ch3ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch3ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Ch3ThresholdEditFieldValueChanged, true);
            app.MergeSwitchLabel = uilabel(app.UIFigure);
            app.MergeSwitchLabel.Text = 'Merge';
            app.MergeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MergeSwitch.ValueChangedFcn = createCallbackFcn(app, @MergeSwitchValueChanged, true);
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.IndividualChannelsSwitchLabel = uilabel(app.UIFigure);
            app.IndividualChannelsSwitchLabel.Text = 'Individual Channels?';
            app.IndividualChannelsSwitch = uiswitch(app.UIFigure, 'rocker');
            app.IndividualChannelsSwitch.Items = {'No', 'Yes'};
            app.IndividualChannelsSwitch.ItemsData = {'No', 'Yes'};
            app.IndividualChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @IndividualChannelsSwitchValueChanged, true);
            app.IndividualChannelsSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fishFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end